classdef SarConnect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        addinportCheckBox       matlab.ui.control.CheckBox
        addoutportCheckBox      matlab.ui.control.CheckBox
        ADDPORTButton           matlab.ui.control.Button
        PortNameEditField       matlab.ui.control.EditField
        PortNameEditFieldLabel  matlab.ui.control.Label
        inputlistListBox        matlab.ui.control.ListBox
        inputlistListBoxLabel   matlab.ui.control.Label
        outputlistListBox       matlab.ui.control.ListBox
        outputlistListBoxLabel  matlab.ui.control.Label
        BackButton              matlab.ui.control.Button
        ExploreDropDown         matlab.ui.control.DropDown
        ExploreDropDownLabel    matlab.ui.control.Label
        ModelPathTextArea       matlab.ui.control.TextArea
        ModelPathTextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        %-------------------------
        ArchModel                %  ArchModel
        CrntComposition = {};       % current level composition
        ChildCompositionNum = 0;
        ChildCompositionCell = {};
        ChildComponentNum = 0;
        ChildComponetCell = {};
        CompPathStr = '';

    end
    
    methods (Access = private)
        
        function updateChildItemsList(app)
            % get child comp information
            [tmpCompsItems,tmpCompsItemsData] = SarGetChildComposItem(app.ChildCompositionNum, app.ChildCompositionCell);
            app.ExploreDropDown.Items = tmpCompsItems;
            app.ExploreDropDown.ItemsData = tmpCompsItemsData;
            [tmpCompnItems,tmpCompnItemsData] = SarGetChildComposItem(app.ChildComponentNum, app.ChildComponetCell);
            
            sumTmpCompItems = [tmpCompsItems tmpCompnItems];
            app.inputlistListBox.Items = sumTmpCompItems;
            app.outputlistListBox.Items = sumTmpCompItems;
            
            sumTmpCompItemsData = [tmpCompsItemsData tmpCompnItemsData];
            
            app.inputlistListBox.ItemsData = sumTmpCompItems; % sumTmpCompItemsData;
            app.outputlistListBox.ItemsData = sumTmpCompItems; % sumTmpCompItemsData;
        end
        
        function exploreToSelectCompsition(app, selectComposition)
            [app.CrntComposition, app.ChildCompositionNum, app.ChildCompositionCell, app.ChildComponentNum, app.ChildComponetCell, app.CompPathStr] = SarDiveIntoSelectComps(selectComposition, app.CompPathStr); 
            app.ModelPathTextArea.Value = app.CompPathStr;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ArchModel = autosar.arch.loadModel(bdroot);

            exploreToSelectCompsition(app, app.ArchModel); 
            updateChildItemsList(app);

            % display in console
            displayCrtLevelInfo(app.CrntComposition, app.ChildCompositionNum, app.ChildCompositionCell, app.ChildComponentNum, app.ChildComponetCell, app.CompPathStr);

        end

        % Value changed function: ExploreDropDown
        function ExploreDropDownValueChanged(app, event)
           
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if isempty(app.CrntComposition.Parent)
                % disp(" ");
                % warning("~~~~~can't backward any more~~~~~");
            else
                expression = '/(\w*)/(\w*)$';
    
                app.CompPathStr = regexprep(app.CompPathStr,expression,'');
                
                exploreToSelectCompsition(app, app.CrntComposition.Parent);
                updateChildItemsList(app);


            end

            displayCrtLevelInfo(app.CrntComposition, app.ChildCompositionNum, app.ChildCompositionCell, app.ChildComponentNum, app.ChildComponetCell, app.CompPathStr);


        end

        % Clicked callback: ExploreDropDown
        function ExploreDropDownClicked(app, event)
            num = event.InteractionInformation.Item;
            disp(num);
            if ~isempty(num)
                % disp('..');
                if app.ChildCompositionNum == 0
                    % warning("!!  current composition has no child  !!");
                else
                    exploreToSelectCompsition(app, app.ChildCompositionCell(num));
                    updateChildItemsList(app);
                end

                displayCrtLevelInfo(app.CrntComposition, app.ChildCompositionNum, app.ChildCompositionCell, app.ChildComponentNum, app.ChildComponetCell, app.CompPathStr);

            end
           
        end

        % Button pushed function: ADDPORTButton
        function ADDPORTButtonPushed(app, event)
            disp('---------------------------------------');

            if app.outputlistListBox.Enable
                disp(app.inputlistListBox.Value);
            end

            if app.inputlistListBox.Enable
                disp(app.outputlistListBox.Value);
            end
           
            
            disp('----- selected port -------------------');
            disp(app.inputlistListBox.Value{1});
        end

        % Value changed function: addoutportCheckBox
        function addoutportCheckBoxValueChanged(app, event)
            value = app.addoutportCheckBox.Value;
            app.outputlistListBox.Enable = value;
        end

        % Value changed function: addinportCheckBox
        function addinportCheckBoxValueChanged(app, event)
            value = app.addinportCheckBox.Value;
            app.inputlistListBox.Enable = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModelPathTextAreaLabel = uilabel(app.UIFigure);
            app.ModelPathTextAreaLabel.Text = 'Model Path';
            app.ModelPathTextArea = uitextarea(app.UIFigure);
            app.ModelPathTextArea.Editable = 'off';
            app.ExploreDropDownLabel = uilabel(app.UIFigure);
            app.ExploreDropDownLabel.Text = 'Explore';
            app.ExploreDropDown = uidropdown(app.UIFigure);
            app.ExploreDropDown.Items = {'Current', 'Option 1', 'Option 2', 'Option 3'};
            app.ExploreDropDown.ItemsData = [0 1 2 3];
            app.ExploreDropDown.ValueChangedFcn = createCallbackFcn(app, @ExploreDropDownValueChanged, true);
            app.ExploreDropDown.ClickedFcn = createCallbackFcn(app, @ExploreDropDownClicked, true);
            app.ExploreDropDown.Value = 0;
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.outputlistListBoxLabel = uilabel(app.UIFigure);
            app.outputlistListBoxLabel.Text = 'output list';
            app.outputlistListBox = uilistbox(app.UIFigure);
            app.inputlistListBoxLabel = uilabel(app.UIFigure);
            app.inputlistListBoxLabel.Text = 'input list';
            app.inputlistListBox = uilistbox(app.UIFigure);
            app.inputlistListBox.Multiselect = 'on';
            app.inputlistListBox.Value = {'Item 1'};
            app.PortNameEditFieldLabel = uilabel(app.UIFigure);
            app.PortNameEditFieldLabel.Text = 'Port Name';
            app.PortNameEditField = uieditfield(app.UIFigure, 'text');
            app.ADDPORTButton = uibutton(app.UIFigure, 'push');
            app.ADDPORTButton.ButtonPushedFcn = createCallbackFcn(app, @ADDPORTButtonPushed, true);
            app.ADDPORTButton.Text = 'ADD PORT';
            app.addoutportCheckBox = uicheckbox(app.UIFigure);
            app.addoutportCheckBox.ValueChangedFcn = createCallbackFcn(app, @addoutportCheckBoxValueChanged, true);
            app.addoutportCheckBox.Text = 'add outport';
            app.addoutportCheckBox.Value = true;
            app.addinportCheckBox = uicheckbox(app.UIFigure);
            app.addinportCheckBox.ValueChangedFcn = createCallbackFcn(app, @addinportCheckBoxValueChanged, true);
            app.addinportCheckBox.Text = 'add inport';
            app.addinportCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SarConnect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end